classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        rae_field                       matlab.ui.control.NumericEditField
        RelativeNormLabel               matlab.ui.control.Label
        image_size_label                matlab.ui.control.Label
        compression_ratio_field         matlab.ui.control.NumericEditField
        CompressionRatioEditFieldLabel  matlab.ui.control.Label
        singular_values_field           matlab.ui.control.NumericEditField
        SingularValuesEditFieldLabel    matlab.ui.control.Label
        original_label                  matlab.ui.control.Label
        compressed_label                matlab.ui.control.Label
        original_image                  matlab.ui.control.Image
        compressed_image                matlab.ui.control.Image
        rank_slider                     matlab.ui.control.Slider
        RankSliderLabel                 matlab.ui.control.Label
    end

    properties (Access = private)
        img_size_x = 0;
        img_size_y = 0;
        channels = 0;
        sv_total = 0;
        img;
        svd_matrix;
        temp_num = 0;
        org_matrix_norm = 0.0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: rank_slider
        function rank_sliderValueChanged(app, event)
            sv_num = round(app.rank_slider.Value);
            
            if (app.img_size_x == 0 || app.img_size_y == 0)
                return;
            end

            % Calculate the low-rank approximation
            app.svd_matrix = zeros(app.img_size_x, app.img_size_y, app.channels);
            for i=1:app.channels
                [U,S,V] = svd(double(app.img(:,:,i)), "econ");
                V_conj = V';
                for j=1:sv_num
                    app.svd_matrix(:,:,i) = app.svd_matrix(:,:,i) + S(j,j) * U(:,j) * V_conj(j,:);
                end
            end
            delete ./temp/*.png;
            imwrite(uint8(app.svd_matrix), sprintf("./temp/temp_%d.png",app.temp_num));
            app.compressed_image.ImageSource = sprintf("./temp/temp_%d.png",app.temp_num);
            app.temp_num = app.temp_num + 1;

            % Compute the norm
            app.rae_field.Value = abs(1 - norm(app.svd_matrix, "fro") / app.org_matrix_norm); % Frobenius norm

            % Calculate the compression ratio
            comp_ratio = (app.img_size_x * app.img_size_y) / (sv_num * (app.img_size_x + app.img_size_y + 1));
            app.compression_ratio_field.Value = comp_ratio;
            app.singular_values_field.Value = sv_num;
        end

        % Image clicked function: original_image
        function original_imageImageClicked(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', 'Image Files'}, 'Select an Image');

            % If user canceled the operation
            if isequal(file, 0)
                return;
            end

            % Show the original image
            imageFilePath = fullfile(path, file);
            app.original_image.ImageSource = imageFilePath;

            % Read image and get infos
            app.img = imread(imageFilePath);
            [app.img_size_x, app.img_size_y, app.channels] = size(app.img);
            app.image_size_label.Text = sprintf("%dx%d", app.img_size_x, app.img_size_y);
            app.sv_total = min(app.img_size_x, app.img_size_y);
            app.singular_values_field.Value = app.sv_total;
            app.rank_slider.Limits = [1, app.sv_total];
            app.rank_slider.Value = app.sv_total;
            app.org_matrix_norm = norm(double(app.img), "fro"); % Frobenius norm
            app.rae_field.Value = 0.0;

            % Set the compressed image to the original one
            imwrite(app.img, "./temp/temp_0.png");
            app.compressed_image.ImageSource = "./temp/temp_0.png";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RankSliderLabel = uilabel(app.UIFigure);
            app.RankSliderLabel.Text = 'Rank';
            app.rank_slider = uislider(app.UIFigure);
            app.rank_slider.Limits = [1 100];
            app.rank_slider.ValueChangedFcn = createCallbackFcn(app, @rank_sliderValueChanged, true);
            app.rank_slider.Value = 1;
            app.compressed_image = uiimage(app.UIFigure);
            app.original_image = uiimage(app.UIFigure);
            app.original_image.ImageClickedFcn = createCallbackFcn(app, @original_imageImageClicked, true);
            app.compressed_label = uilabel(app.UIFigure);
            app.compressed_label.Text = 'Compressed';
            app.original_label = uilabel(app.UIFigure);
            app.original_label.Text = 'Original';
            app.SingularValuesEditFieldLabel = uilabel(app.UIFigure);
            app.SingularValuesEditFieldLabel.Text = 'Singular Values';
            app.singular_values_field = uieditfield(app.UIFigure, 'numeric');
            app.singular_values_field.ValueDisplayFormat = '%d';
            app.singular_values_field.Editable = 'off';
            app.CompressionRatioEditFieldLabel = uilabel(app.UIFigure);
            app.CompressionRatioEditFieldLabel.Text = 'Compression Ratio';
            app.compression_ratio_field = uieditfield(app.UIFigure, 'numeric');
            app.compression_ratio_field.ValueDisplayFormat = '%1.4f';
            app.compression_ratio_field.Editable = 'off';
            app.image_size_label = uilabel(app.UIFigure);
            app.image_size_label.Text = 'image_size';
            app.RelativeNormLabel = uilabel(app.UIFigure);
            app.RelativeNormLabel.Text = 'Relative Absolute Error';
            app.rae_field = uieditfield(app.UIFigure, 'numeric');
            app.rae_field.ValueDisplayFormat = '%1.6f';
            app.rae_field.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end